classdef VectorWindowing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SignalWindowingUIFigure       matlab.ui.Figure
        PaddingCheckBox               matlab.ui.control.CheckBox
        VisualizationCheckBox         matlab.ui.control.CheckBox
        OverlapCheckBox               matlab.ui.control.CheckBox
        PercentSliderLabel            matlab.ui.control.Label
        PercentSlider                 matlab.ui.control.Slider
        LengthEditFieldLabel          matlab.ui.control.Label
        LengthEditField               matlab.ui.control.NumericEditField
        SamplingRateHzEditFieldLabel  matlab.ui.control.Label
        SamplingRateHzEditField       matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        ResetButton                   matlab.ui.control.Button
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes_3                      matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        FourierTransformCheckBox      matlab.ui.control.CheckBox
        ShapeLabel                    matlab.ui.control.Label
        ShapeDropDown                 matlab.ui.control.DropDown
        StartButton                   matlab.ui.control.Button
        UIAxes4                       matlab.ui.control.UIAxes
        OpenFileButton                matlab.ui.control.Button
        TextArea                      matlab.ui.control.TextArea
        PaddingValueEditFieldLabel    matlab.ui.control.Label
        PaddingValueEditField         matlab.ui.control.EditField
        TypethenameofavariablefromWorkspaceLabel  matlab.ui.control.Label
        TypethenameofavariablefromWorkspaceEditField  matlab.ui.control.EditField
        OrLabel                       matlab.ui.control.Label
        UnitLabel                     matlab.ui.control.Label
        UnitDropDown                  matlab.ui.control.DropDown
        AnimationCheckBox             matlab.ui.control.CheckBox
        FactorEditFieldLabel          matlab.ui.control.Label
        FactorEditField               matlab.ui.control.NumericEditField
        ModeLabel                     matlab.ui.control.Label
        ModeDropDown                  matlab.ui.control.DropDown
        WindowLabel                   matlab.ui.control.Label
        UIAxes4_2                     matlab.ui.control.UIAxes
        DefaultCheckBox               matlab.ui.control.CheckBox
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SamplingRateHzEditField
        function SamplingRateHzEditFieldValueChanged(app, event)
            Fs =   event.Value;
            app.SamplingRateHzEditField.Value = Fs;
        end

        % Value changed function: LengthEditField
        function LengthEditFieldValueChanged(app, event)
            WL = event.Value;
            app.LengthEditField.Value = WL;
        end

        % Value changed function: PaddingCheckBox
        function PaddingCheckBoxValueChanged(app, event)
            Pad = event.Value;
            app.PaddingCheckBox.Value = Pad;
        end

        % Callback function
        function PaddingValueEditFieldValueChanged(app, event)
            PadVal = event.Value;
            app.PaddingValueEditField.Value = PadVal;
        end

        % Value changed function: VisualizationCheckBox
        function VisualizationCheckBoxValueChanged(app, event)
            Vis = event.Value;
            app.VisualizationCheckBox.Value = Vis;
        end

        % Value changed function: FourierTransformCheckBox
        function FourierTransformCheckBoxValueChanged(app, event)
            FFT = event.Value;
            app.FourierTransformCheckBox.Value = FFT;
        end

        % Value changed function: ShapeDropDown
        function ShapeDropDownValueChanged(app, event)
            WS = event.Value;
            app.ShapeDropDown.Value = WS;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Clearing Variables
            app.ShapeDropDown.Value = 'Kaiser';
            app.FourierTransformCheckBox.Value = 1;
            app.VisualizationCheckBox.Value = 1;
            app.PaddingValueEditField.Value = "0";
            app.PaddingCheckBox.Value = 1;
            app.LengthEditField.Value = 1;
            app.SamplingRateHzEditField.Value = 256;
            app.OverlapCheckBox.Value = 1;
            app.PercentSlider.Value = 0;
            app.UnitDropDown.Value = "Seconds";
            app.AnimationCheckBox.Value = 0;
            app.FactorEditField.Value = 0;
            app.ModeDropDown.Value = "Symmetric";
            
            % Resetting Axes
            cla(app.UIAxes),axis(app.UIAxes,'auto')
            cla(app.UIAxes2),axis(app.UIAxes2,'auto')
            cla(app.UIAxes3),axis(app.UIAxes3,'auto')
            cla(app.UIAxes_2),axis(app.UIAxes_2,'auto')
            cla(app.UIAxes_3),axis(app.UIAxes_3,'auto')
            cla(app.UIAxes4),axis(app.UIAxes4,'auto')
            cla(app.UIAxes4_2),axis(app.UIAxes4_2,'auto')
        end

        % Value changed function: OverlapCheckBox
        function OverlapCheckBoxValueChanged(app, event)
            ovp = event.Value;
            app.OverlapCheckBox.Value = ovp;
        end

        % Value changed function: PercentSlider
        function PercentSliderValueChanged(app, event)
            OL = event.Value;
            app.PercentSlider.Value = OL;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Initializing Axes
            cla(app.UIAxes),axis(app.UIAxes,'auto')
            cla(app.UIAxes2),axis(app.UIAxes2,'auto')
            cla(app.UIAxes3),axis(app.UIAxes3,'auto')
            cla(app.UIAxes_2),axis(app.UIAxes_2,'auto')
            cla(app.UIAxes_3),axis(app.UIAxes_3,'auto')
            cla(app.UIAxes4),axis(app.UIAxes4,'auto')
            cla(app.UIAxes4_2),axis(app.UIAxes4_2,'auto')
            
            % Getting Input Signal
            if ~isempty(app.TypethenameofavariablefromWorkspaceEditField.Value)
                
                VarName = app.TypethenameofavariablefromWorkspaceEditField.Value;
                s = evalin('base', VarName); % Load Variable from MATLAB Workspace
                
            else
                
                PF = (app.TextArea.Value);
                FN = PF(1);
                FN = string(FN);
                s = load(FN);
                s = cell2mat(struct2cell(s)); % Load the Selected File
                
            end
            
            % Reading Global Variables
            Fs = app.SamplingRateHzEditField.Value; % Sampling Rate
            Unit = app.UnitDropDown.Value; % Unit: Seconds/Samples
            WS = app.ShapeDropDown.Value; % Window Shape
            WL = app.LengthEditField.Value; % Window Length
            Factor = app.FactorEditField.Value; % Window Second Input (Window Factor)
            Def = app.DefaultCheckBox.Value;
            Mode = app.ModeDropDown.Value; % Window Mode: Symmetric/Periodic
            Pad = app.PaddingCheckBox.Value; % 0: Windowing without Padding, 1: Windowing with Padding
            PadVal = str2double(app.PaddingValueEditField.Value); % Value that Used for Padding, default: 0
            OL = app.PercentSlider.Value; % Overlapping Percent
            Windowing.OverlapPercent = OL; % Overlapping Percent (Output)
            OL = round(WL*(OL/100)); % Converting the Overlapping Percent into the Number of Samples
            ovp = app.OverlapCheckBox.Value; % 0: Windowing without Overlap, 1: Windowing with Overlap
            if ovp == 0
                OL = 0; % In Case of "Without Overlap"
            end
            Vis = app.VisualizationCheckBox.Value; % 0: Visualizatio Off, 1: Visualization On
            Animation = app.AnimationCheckBox.Value; % 0: Animation Off, 1: Animation On
            FFT = app.FourierTransformCheckBox.Value; % 0: FFT Off, 1: FFT On
            
            % Computing Global Parametes and Setting Plotting Axes
            L = length(s);
            switch Unit
                
                case "Seconds"
                    
                    WL = ceil(WL*Fs);
                    Ts = 1/Fs;
                    t = (0:L-1)*Ts;
                    tw = (0:WL-1)*Ts;
                    xlabel(app.UIAxes2,'Time (s)')
                    xlabel(app.UIAxes3,'Frequency (Hz)')
                    xlabel(app.UIAxes4,'Time (s)')
                    xlabel(app.UIAxes4_2,'Frequency (Hz)')
                    xlabel(app.UIAxes,'Time (s)')
                    xlabel(app.UIAxes_3,'Frequency(Hz)')
                    xlabel(app.UIAxes_2,'Time (s)')
                    
                case "Samples"
                    
                    Fs = "Samples";
                    t = 1:L;
                    xlabel(app.UIAxes2,'Samples')
                    xlabel(app.UIAxes3,'Frequency')
                    xlabel(app.UIAxes4,'Samples')
                    xlabel(app.UIAxes4_2,'Frequency')
                    xlabel(app.UIAxes,'Samples')
                    xlabel(app.UIAxes_3,'Frequency')
                    xlabel(app.UIAxes_2,'Samples')
                    
            end
            
            % Checking Inputs
            if WL>length(s)
                
                errordlg('Window length should be shorter than the signal length','Error')
                
            end
            
            % FFT of Original Signal
            if FFT==1
                
                NFFT = 2^nextpow2(L);
                SP = abs(fftshift(fft(s,NFFT))).^2/NFFT;
                
                if Unit=="Seconds"
                    
                    f = (-NFFT/2:NFFT/2-1)*(Fs/NFFT);
                    Windowing.SignalPowerSpectrum = [f;SP];
                    
                else
                    
                    Windowing.SignalPowerSpectrum = SP;
                    
                end
                
            end
            
            % Forming the Output Variable
            Windowing.OriginalSignal = s;
            Windowing.SamplingRate = Fs;
            Windowing.TimeBorder = t;
            Windowing.WindowLength = WL;
            Windowing.WindowShape = WS;
            
            % Padding the Signal if It Is Neccessary Using PadVal
            if Pad==1 && mod(length(s),(WL-OL))~=0
                
                Npad = WL-mod(length(s),(WL-OL));
                PAD = repmat(PadVal,[1,Npad]);
                s = [s,PAD];
                L = length(s);
                Windowing.PaddedSignal = s;
                
                if Unit=="Seconds"
                    
                    t = (0:L-1)*Ts;
                    
                end
                
            end
            
            % Visualizing The Original Signal In Time and Frequency Domain
            if Vis==1 && Unit=="Seconds"
                
                plot(app.UIAxes2,t,s)
                
                if FFT==1
                    
                    plot(app.UIAxes3,f,SP)
                    xlim(app.UIAxes3,[f(1),f(end)])
                    
                end
                
            elseif Vis==1 && Unit=="Samples"
                
                plot(app.UIAxes2,s)
                
                if FFT==1
                    
                    plot(app.UIAxes3,SP)
                    
                end
                
            end
            
            % Creating the Desired Window
            if Def==1
                
                switch WS
                    
                    case 'Rectangular'
                        Win = rectwin(WL);
                    case 'Triangular'
                        Win = triang(WL);
                    case 'Gaussian'
                        Win = gausswin(WL);
                    case 'Hamming'
                        Win = hamming(WL,Mode);
                    case 'Hanning'
                        Win = hann(WL,Mode);
                    case 'Tukey'
                        Win = tukeywin(WL);
                    case 'Kaiser'
                        Win = kaiser(WL);
                    case 'Chebyshev'
                        Win = chebwin(WL);
                    case 'Blackman'
                        Win = blackman(WL,Mode);
                    case 'Blackman-Harris'
                        Win = blackmanharris(WL,Mode);
                    case 'Bartlet'
                        Win = bartlett(WL);
                    case 'Bartlet-Hanning'
                        Win = barthannwin(WL);
                    case 'Nuttall'
                        Win = nuttallwin(WL,Mode);
                    case 'Parzen'
                        Win = parzenwin(WL);
                    case 'Taylor'
                        Win = taylorwin(WL);
                    case 'Flat Top'
                        Win = flattopwin(WL,Mode);
                    case 'Bohman'
                        Win = bohmanwin(WL);
                        
                end
                
            elseif Def==0
                
                switch WS
                    
                    case 'Rectangular'
                        Win = rectwin(WL);
                    case 'Triangular'
                        Win = triang(WL);
                    case 'Gaussian'
                        Win = gausswin(WL,Factor);
                    case 'Hamming'
                        Win = hamming(WL,Mode);
                    case 'Hanning'
                        Win = hann(WL,Mode);
                    case 'Tukey'
                        Win = tukeywin(WL,Factor);
                    case 'Kaiser'
                        Win = kaiser(WL,Factor);
                    case 'Chebyshev'
                        Win = chebwin(WL,Factor);
                    case 'Blackman'
                        Win = blackman(WL,Mode);
                    case 'Blackman-Harris'
                        Win = blackmanharris(WL,Mode);
                    case 'Flat Top'
                        Win = flattopwin(WL,Mode);
                    case 'Bohman'
                        Win = bohmanwin(WL);
                    case 'Bartlet'
                        Win = bartlett(WL);
                    case 'Bartlet-Hanning'
                        Win = barthannwin(WL);
                    case 'Nuttall'
                        Win = nuttallwin(WL,Mode);
                    case 'Parzen'
                        Win = parzenwin(WL);
                    case 'Taylor'
                        Win = taylorwin(WL,Factor);
                        
                end
                
            end
            
            Win = Win';
            
            % Computing Power Spectrum of the Window Using Welch's Method
            % Visualizing the Window in Time and Frequency Domain
            switch Unit
                
                case "Seconds"
                    
                    [PW,fw] = pwelch(Win,[],[],[],Fs);
                    PW = 20*log10(PW);
                    Windowing.Window = [tw;Win];
                    Windowing.WindowPowerSpectrum = [fw,PW]';
                    
                    if Vis==1
                        
                        plot(app.UIAxes4,tw,Win,'-r','LineWidth',2)
                        xlim(app.UIAxes4,[0-1,tw(end)+1])
                        title(app.UIAxes4,[WS,'  Window'])
                        plot(app.UIAxes4_2,fw,PW)
                        
                    end
                    
                case "Samples"
                    
                    PW = 20*log10(pwelch(Win));
                    Windowing.Window = Win;
                    Windowing.WindowPowerSpectrum = PW;
                    
                    if Vis==1
                        
                        plot(app.UIAxes4,Win,'-r','LineWidth',2)
                        xlim(app.UIAxes4,[0-1,length(Win)+1])
                        title(app.UIAxes4,[WS,'  Window'])
                        plot(app.UIAxes4_2,PW)
                        
                    end
                    
            end
            Windowing.WindowMode = Mode;
            Windowing.WindowFactor = Factor;
            
            % Applying the Window to The Signal
            stp = WL-OL;
            n = fix((L-OL)/stp);
            D = zeros(n,WL);
            if Unit=="Seconds"
                TW = zeros(n,WL);
            end
            
            for i = 1:n
                
                D(i,:) = Win.*s((i-1)*stp+(1:WL));
                if Unit=="Seconds"
                    TW(i,:) = t((i-1)*stp+(1:WL));
                end
                
                if Vis==1
                    
                    hold(app.UIAxes_2,'on')
                    
                    switch Unit
                        case "Seconds"
                            plot(app.UIAxes_2,tw,D(i,:))
                        case "Samples"
                            plot(app.UIAxes_2,D(i,:))
                    end
                    
                    if Animation==1
                        drawnow
                        pause(0.1)
                    end
                    
                end
                
            end
            
            % Saving Segmented Signal and Its Time Borders in the Output Variable
            Windowing.SegmentedSignal = D;
            if Unit=="Seconds"
                Windowing.SegmentTimes = TW;
            end
            
            % Showing a Random Window in Time and Frequency Domain
            if Vis==1
                
                I = randperm(n,1);
                x = D(I,:);
                lx = length(x);
                
                if FFT==1 && Unit=="Seconds"
                    
                    tx = TW(I,:);
                    nfft = 2^nextpow2(lx);
                    XP = abs(fftshift(fft(x,nfft))).^2/nfft;
                    fx = (-nfft/2:nfft/2-1)*(Fs/nfft);
                    plot(app.UIAxes,tx,x)
                    plot(app.UIAxes_3,fx,XP)
                    xlim(app.UIAxes_3,[fx(1),fx(end)])
                    
                elseif FFT==0 && Unit=="Seconds"
                    
                    tx = TW(I,:);
                    plot(app.UIAxes,tx,x)
                    
                elseif FFT==1 && Unit=="Samples"
                    
                    nfft = 2^nextpow2(lx);
                    XP = abs(fftshift(fft(x,nfft))).^2/nfft;
                    plot(app.UIAxes,x)
                    plot(app.UIAxes_3,XP)
                    
                elseif FFT==0 && Unit=="Samples"
                    
                    plot(app.UIAxes,x)
                    
                end
                
            end
            
            % Sending the Output Variable "Windowing" into MATLAB Workspace
            assignin("base","Windowing",Windowing);
            
            % END
        end

        % Button pushed function: OpenFileButton
        function OpenFileButtonPushed(app, event)
            [file,path] = uigetfile('*.mat','Please select a file who contains the signal');
            FN = [path,file];
            app.TextArea.Value = {FN;path};
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            PF = app.TextArea.Value;
            app.TextArea.Value = PF;
        end

        % Value changed function: 
        % TypethenameofavariablefromWorkspaceEditField
        function TypethenameofavariablefromWorkspaceEditFieldValueChanged(app, event)
            VarName = event.Value;
            app.TypethenameofavariablefromWorkspaceEditField.Value = VarName;
        end

        % Value changed function: PaddingValueEditField
        function PaddingValueEditFieldValueChanged2(app, event)
            PV = event.Value;
            app.PaddingValueEditField.Value = PV;
        end

        % Value changed function: UnitDropDown
        function UnitDropDownValueChanged(app, event)
            Unit = event.Value;
            app.UnitDropDown.Value = Unit;
        end

        % Value changed function: AnimationCheckBox
        function AnimationCheckBoxValueChanged(app, event)
            Animation = event.Value;
            app.AnimationCheckBox.Value = Animation;
        end

        % Value changed function: FactorEditField
        function FactorEditFieldValueChanged(app, event)
            Factor = event.Value;
            app.FactorEditField.Value = Factor;
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            Mode = event.Value;
            app.ModeDropDown.Value = Mode;
        end

        % Value changed function: DefaultCheckBox
        function DefaultCheckBoxValueChanged(app, event)
            Def = event.Value;
            app.DefaultCheckBox.Value = Def;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SignalWindowingUIFigure = uifigure('Visible', 'off');
            app.SignalWindowingUIFigure.Name = 'Signal Windowing';
            app.PaddingCheckBox = uicheckbox(app.SignalWindowingUIFigure);
            app.PaddingCheckBox.ValueChangedFcn = createCallbackFcn(app, @PaddingCheckBoxValueChanged, true);
            app.PaddingCheckBox.Text = 'Padding';
            app.PaddingCheckBox.Value = true;
            app.VisualizationCheckBox = uicheckbox(app.SignalWindowingUIFigure);
            app.VisualizationCheckBox.ValueChangedFcn = createCallbackFcn(app, @VisualizationCheckBoxValueChanged, true);
            app.VisualizationCheckBox.Text = 'Visualization';
            app.VisualizationCheckBox.Value = true;
            app.OverlapCheckBox = uicheckbox(app.SignalWindowingUIFigure);
            app.OverlapCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlapCheckBoxValueChanged, true);
            app.OverlapCheckBox.Text = 'Overlap:';
            app.OverlapCheckBox.Value = true;
            app.PercentSliderLabel = uilabel(app.SignalWindowingUIFigure);
            app.PercentSliderLabel.Text = 'Percent';
            app.PercentSlider = uislider(app.SignalWindowingUIFigure);
            app.PercentSlider.Limits = [0 99];
            app.PercentSlider.ValueChangedFcn = createCallbackFcn(app, @PercentSliderValueChanged, true);
            app.LengthEditFieldLabel = uilabel(app.SignalWindowingUIFigure);
            app.LengthEditFieldLabel.Text = 'Length:';
            app.LengthEditField = uieditfield(app.SignalWindowingUIFigure, 'numeric');
            app.LengthEditField.ValueChangedFcn = createCallbackFcn(app, @LengthEditFieldValueChanged, true);
            app.LengthEditField.Value = 1;
            app.SamplingRateHzEditFieldLabel = uilabel(app.SignalWindowingUIFigure);
            app.SamplingRateHzEditFieldLabel.Text = 'Sampling Rate (Hz):';
            app.SamplingRateHzEditField = uieditfield(app.SignalWindowingUIFigure, 'numeric');
            app.SamplingRateHzEditField.RoundFractionalValues = 'on';
            app.SamplingRateHzEditField.ValueDisplayFormat = '%.0f';
            app.SamplingRateHzEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingRateHzEditFieldValueChanged, true);
            app.SamplingRateHzEditField.Value = 256;
            app.UIAxes = uiaxes(app.SignalWindowingUIFigure);
            title(app.UIAxes, 'Rwndom Widow-Time Domain')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            app.UIAxes2 = uiaxes(app.SignalWindowingUIFigure);
            title(app.UIAxes2, 'Original Signal')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Amplitude')
            app.ResetButton = uibutton(app.SignalWindowingUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.UIAxes_2 = uiaxes(app.SignalWindowingUIFigure);
            title(app.UIAxes_2, 'Segmented Signal')
            xlabel(app.UIAxes_2, 'Time (s)')
            ylabel(app.UIAxes_2, 'Amplitude')
            app.UIAxes_3 = uiaxes(app.SignalWindowingUIFigure);
            title(app.UIAxes_3, 'Random Window-Frequency Domain')
            xlabel(app.UIAxes_3, 'Frequency (Hz)')
            ylabel(app.UIAxes_3, 'Magnitude')
            app.UIAxes3 = uiaxes(app.SignalWindowingUIFigure);
            title(app.UIAxes3, 'Fast Fourier Transform (FFT)')
            xlabel(app.UIAxes3, 'Frequency (Hz)')
            ylabel(app.UIAxes3, 'Magnitude')
            app.FourierTransformCheckBox = uicheckbox(app.SignalWindowingUIFigure);
            app.FourierTransformCheckBox.ValueChangedFcn = createCallbackFcn(app, @FourierTransformCheckBoxValueChanged, true);
            app.FourierTransformCheckBox.Text = 'Fourier Transform';
            app.FourierTransformCheckBox.Value = true;
            app.ShapeLabel = uilabel(app.SignalWindowingUIFigure);
            app.ShapeLabel.Text = 'Shape:';
            app.ShapeDropDown = uidropdown(app.SignalWindowingUIFigure);
            app.ShapeDropDown.Items = {'Rectangular', 'Triangular', 'Gaussian', 'Hamming', 'Hanning', 'Tukey', 'Kaiser', 'Chebyshev', 'Blackman', 'Blackman-Harris', 'Flat Top', 'Bohman', 'Bartlet', 'Bartlet-Hanning', 'Nuttall', 'Parzen', 'Taylor'};
            app.ShapeDropDown.Editable = 'on';
            app.ShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @ShapeDropDownValueChanged, true);
            app.ShapeDropDown.Value = 'Kaiser';
            app.StartButton = uibutton(app.SignalWindowingUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.UIAxes4 = uiaxes(app.SignalWindowingUIFigure);
            title(app.UIAxes4, 'Window Shape')
            xlabel(app.UIAxes4, 'Time (s)')
            ylabel(app.UIAxes4, 'Amplitude')
            app.OpenFileButton = uibutton(app.SignalWindowingUIFigure, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFileButton.Text = {'Open'; 'File'};
            app.TextArea = uitextarea(app.SignalWindowingUIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.PaddingValueEditFieldLabel = uilabel(app.SignalWindowingUIFigure);
            app.PaddingValueEditFieldLabel.Text = 'Padding Value:';
            app.PaddingValueEditField = uieditfield(app.SignalWindowingUIFigure, 'text');
            app.PaddingValueEditField.ValueChangedFcn = createCallbackFcn(app, @PaddingValueEditFieldValueChanged2, true);
            app.PaddingValueEditField.Value = '0';
            app.TypethenameofavariablefromWorkspaceLabel = uilabel(app.SignalWindowingUIFigure);
            app.TypethenameofavariablefromWorkspaceLabel.Text = {'Type the name of'; 'a variable from Workspace'};
            app.TypethenameofavariablefromWorkspaceEditField = uieditfield(app.SignalWindowingUIFigure, 'text');
            app.TypethenameofavariablefromWorkspaceEditField.ValueChangedFcn = createCallbackFcn(app, @TypethenameofavariablefromWorkspaceEditFieldValueChanged, true);
            app.OrLabel = uilabel(app.SignalWindowingUIFigure);
            app.OrLabel.Text = 'Or';
            app.UnitLabel = uilabel(app.SignalWindowingUIFigure);
            app.UnitLabel.Text = 'Unit:';
            app.UnitDropDown = uidropdown(app.SignalWindowingUIFigure);
            app.UnitDropDown.Items = {'Seconds', 'Samples'};
            app.UnitDropDown.ItemsData = {'Seconds', 'Samples'};
            app.UnitDropDown.Editable = 'on';
            app.UnitDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitDropDownValueChanged, true);
            app.UnitDropDown.Value = 'Seconds';
            app.AnimationCheckBox = uicheckbox(app.SignalWindowingUIFigure);
            app.AnimationCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnimationCheckBoxValueChanged, true);
            app.AnimationCheckBox.Text = 'Animation';
            app.FactorEditFieldLabel = uilabel(app.SignalWindowingUIFigure);
            app.FactorEditFieldLabel.Text = 'Factor:';
            app.FactorEditField = uieditfield(app.SignalWindowingUIFigure, 'numeric');
            app.FactorEditField.ValueChangedFcn = createCallbackFcn(app, @FactorEditFieldValueChanged, true);
            app.ModeLabel = uilabel(app.SignalWindowingUIFigure);
            app.ModeLabel.Text = 'Mode:';
            app.ModeDropDown = uidropdown(app.SignalWindowingUIFigure);
            app.ModeDropDown.Items = {'Symmetric', 'Periodic'};
            app.ModeDropDown.Editable = 'on';
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'Symmetric';
            app.WindowLabel = uilabel(app.SignalWindowingUIFigure);
            app.WindowLabel.Text = 'Window:';
            app.UIAxes4_2 = uiaxes(app.SignalWindowingUIFigure);
            title(app.UIAxes4_2, 'Power Spectrum')
            xlabel(app.UIAxes4_2, 'Frequency')
            ylabel(app.UIAxes4_2, 'Magnitude (dB)')
            app.DefaultCheckBox = uicheckbox(app.SignalWindowingUIFigure);
            app.DefaultCheckBox.ValueChangedFcn = createCallbackFcn(app, @DefaultCheckBoxValueChanged, true);
            app.DefaultCheckBox.Text = 'Default';
            app.DefaultCheckBox.Value = true;
            % Show the figure after all components are created
            app.SignalWindowingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VectorWindowing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SignalWindowingUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SignalWindowingUIFigure)
        end
    end
end